classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        PlotButton                     matlab.ui.control.Button
        MostinfluentialmodesEditField  matlab.ui.control.NumericEditField
        MostinfluentialmodesEditFieldLabel  matlab.ui.control.Label
        HemisphereDropDown             matlab.ui.control.DropDown
        HemisphereDropDownLabel        matlab.ui.control.Label
        NumberofmodesEditField         matlab.ui.control.NumericEditField
        NumberofmodesEditFieldLabel    matlab.ui.control.Label
        EigenmodesButton               matlab.ui.control.Button
        FDRCheckBox                    matlab.ui.control.CheckBox
        PthresholdEditField            matlab.ui.control.NumericEditField
        PthresholdEditFieldLabel       matlab.ui.control.Label
        PthrtailapproxEditField        matlab.ui.control.NumericEditField
        PthrtailapproxEditFieldLabel   matlab.ui.control.Label
        PermutationEditField           matlab.ui.control.NumericEditField
        PermutationEditFieldLabel      matlab.ui.control.Label
        indicatormatrixGButton         matlab.ui.control.Button
        StatisticaltestDropDown        matlab.ui.control.DropDown
        StatisticaltestDropDownLabel   matlab.ui.control.Label
        MaskButton                     matlab.ui.control.Button
        SurfaceButton                  matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        InputmapsButton                matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        mostinfluentialmodesLabel      matlab.ui.control.Label
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        func = addpath('/projects/kg98/trangc/MBM/func')
        util1 = addpath('/projects/kg98/trangc/MBM/utils/gifti-matlab')
        util2 = addpath('/projects/kg98/trangc/MBM/utils/PALM-master')
        util3 = addpath('/projects/kg98/trangc/MBM/utils/fdr_bh')
        mbm % mbm structure
    end
    
    properties (Access = private)
        y % test
        % For plotting
        light_gray = [0.5 0.5 0.5]; % define color
        light_green = [0.35 0.65 0.35]; % define color
        font_name = 'Arial'; % font for labels
        font_size = 15;


        fig % structure for plotting
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set default values
            app.mbm.plot.vis = false; % visualise results in aseparate figure (in mbm_mbm.m)
            app.mbm.stat.test = 'two sample';   % stat test
            app.mbm.stat.N_per = 50; % number of permutation
            app.mbm.stat.Pthr = 0.1; % threshold of p-value for tail estimation
            app.mbm.mbm.N_eig = 150; % number of eigenmodes for analysing
            app.mbm.plot.hemis = 'left'; % left hemisphere
            app.mbm.plot.N_influ = 6; % number of most influential modes to plot
            app.mbm.stat.fdr = false; % FDR
            app.mbm.stat.thres = 0.05; % p-value to be considered significant
            axis(app.UIAxes,'off');
axis(app.UIAxes, 'image');
axis(app.UIAxes_2,'off');
axis(app.UIAxes_2, 'image');
axis(app.UIAxes_3,'off');
axis(app.UIAxes_3, 'image');
axis(app.UIAxes_4,'off');
axis(app.UIAxes_4, 'image');
        end

        % Button pushed function: InputmapsButton
        function InputmapsButtonPushed(app, event)
            [file,path] = uigetfile('*.gii','MultiSelect','on');
            app.mbm.maps.anat_list = fullfile(path,file);
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            mbm_check_input(app.mbm,app.UIFigure); % check inputs
            
            rng(2); % set the default seed for random generation to ensure the results are reproducible.
            
            app.mbm = mbm_mbm(app.mbm); % analysing using MBM
            
            
        end

        % Button pushed function: SurfaceButton
        function SurfaceButtonPushed(app, event)
            [file,path] = uigetfile('*.vtk');
            app.mbm.plot.vtk = fullfile(path,file);
            
            % read surface
            [vertices,faces] = read_vtk(app.mbm.plot.vtk);
            app.fig.vertices = vertices';
            app.fig.faces = faces';
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            app.mbm.maps.mask_file = fullfile(path,file);
        end

        % Value changed function: StatisticaltestDropDown
        function StatisticaltestDropDownValueChanged(app, event)
            app.mbm.stat.test = app.StatisticaltestDropDown.Value;
            
        end

        % Button pushed function: indicatormatrixGButton
        function indicatormatrixGButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            app.mbm.stat.G = fullfile(path,file);
        end

        % Value changed function: PermutationEditField
        function PermutationEditFieldValueChanged(app, event)
            app.mbm.stat.N_per = app.PermutationEditField.Value;
            
        end

        % Value changed function: PthrtailapproxEditField
        function PthrtailapproxEditFieldValueChanged(app, event)
            app.mbm.stat.Pthr = app.PthrtailapproxEditField.Value;
            
        end

        % Button pushed function: EigenmodesButton
        function EigenmodesButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            app.mbm.mbm.eig_file = fullfile(path,file);
        end

        % Value changed function: NumberofmodesEditField
        function NumberofmodesEditFieldValueChanged(app, event)
            app.mbm.mbm.N_eig = app.NumberofmodesEditField.Value;
            
        end

        % Value changed function: HemisphereDropDown
        function HemisphereDropDownValueChanged(app, event)
            app.mbm.plot.hemis = app.HemisphereDropDown.Value;
            
        end

        % Value changed function: MostinfluentialmodesEditField
        function MostinfluentialmodesEditFieldValueChanged(app, event)
            app.mbm.plot.N_influ = app.MostinfluentialmodesEditField.Value;
            
        end

        % Value changed function: FDRCheckBox
        function FDRCheckBoxValueChanged(app, event)
            app.mbm.stat.fdr = app.FDRCheckBox.Value;
            
        end

        % Value changed function: PthresholdEditField
        function PthresholdEditFieldValueChanged(app, event)
            app.mbm.stat.thres = app.PthresholdEditField.Value;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            % plot tmap
            stat_map_nomask = zeros(size(app.mbm.maps.mask))';
            stat_map_nomask(app.mbm.maps.mask==1) = app.mbm.stat.stat_map;
            
            plot_map(app.UIAxes,app.fig.vertices,app.fig.faces,stat_map_nomask,app.mbm.plot.hemis);
            
            % plot thresholded tmap
            thres_map_nomask = zeros(size(app.mbm.maps.mask))';
            thres_map_nomask(app.mbm.maps.mask==1) = app.mbm.stat.thres_map;
            
            plot_map(app.UIAxes_2,app.fig.vertices,app.fig.faces,thres_map_nomask,app.mbm.plot.hemis);
            
            % plot beta spectrum
            bar(app.UIAxes_3,app.mbm.mbm.beta,'FaceColor',app.light_gray,'EdgeColor',app.light_gray);
            hold(app.UIAxes_3,'on');
            bar(app.UIAxes_3,app.mbm.mbm.sig_beta,'FaceColor',app.light_green,'EdgeColor',app.light_green);
            hold(app.UIAxes_3, 'off')
            
            xlabel(app.UIAxes_3, '\psi', 'FontName', app.font_name, 'FontSize', app.font_size);
            ylabel(app.UIAxes_3, '\beta', 'FontName', app.font_name, 'FontSize', app.font_size);
            
            legend(app.UIAxes_3,'non-significant','significant','NumColumns',2)
            legend(app.UIAxes_3,'boxoff')
            
            % plot significant patterns
            recon_map_nomask = zeros(size(app.mbm.maps.mask))';
            recon_map_nomask(app.mbm.maps.mask==1) = app.mbm.mbm.recon_map;
            
            plot_map(app.UIAxes_4,app.fig.vertices,app.fig.faces,recon_map_nomask,app.mbm.plot.hemis);
            
            % plot the most influential modes
            eig_nomask = zeros(size(app.mbm.maps.mask,2),app.mbm.plot.N_influ);
            eig_nomask(app.mbm.maps.mask==1,:) = app.mbm.mbm.eig(:,app.mbm.mbm.beta_order(1:app.mbm.plot.N_influ)).*sign(app.mbm.mbm.beta(app.mbm.mbm.beta_order(1:app.mbm.plot.N_influ)));
            
            % define axis para
            factor_x = 1.1;
            factor_y = 1.4;
            init_x = 191;
            init_y = 6;
            num_row = 2;    %No of rows
            num_col = ceil(app.mbm.plot.N_influ/2);    %No of columns
            length_x = 522/(factor_x*(num_col-1)+1);
            length_y = 254/(factor_y*(num_row-1)+1);
            
            for eig_in = 1:app.mbm.plot.N_influ % influent order of the modes
                
                i = ceil(eig_in/num_col); % row index
                ii = mod(eig_in+num_col-1,num_col)+1; % column index
                
                aa = uiaxes(app.RightPanel);
                aa.Position = [init_x+factor_x*length_x*(ii-1) init_y+factor_y*length_y*(num_row-i) length_x length_y];
                
                plot_map(aa,app.fig.vertices,app.fig.faces,eig_nomask(:,eig_in),app.mbm.plot.hemis);
                
                switch eig_in
                    case 1
                        aa.Title.String = [num2str(eig_in), 'st'];
                    case 2
                        aa.Title.String = [num2str(eig_in), 'nd'];
                    case 3
                        aa.Title.String = [num2str(eig_in), 'rd'];
                    otherwise
                        aa.Title.String = [num2str(eig_in), 'th'];
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {229, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputmapsButton = uibutton(app.LeftPanel, 'push');
            app.InputmapsButton.ButtonPushedFcn = createCallbackFcn(app, @InputmapsButtonPushed, true);
            app.InputmapsButton.Text = 'Input maps';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SurfaceButton = uibutton(app.LeftPanel, 'push');
            app.SurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @SurfaceButtonPushed, true);
            app.SurfaceButton.Text = 'Surface';
            app.MaskButton = uibutton(app.LeftPanel, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            app.StatisticaltestDropDownLabel = uilabel(app.LeftPanel);
            app.StatisticaltestDropDownLabel.Text = 'Statistical test';
            app.StatisticaltestDropDown = uidropdown(app.LeftPanel);
            app.StatisticaltestDropDown.Items = {'one sample', 'two sample', 'one way ANOVA'};
            app.StatisticaltestDropDown.ValueChangedFcn = createCallbackFcn(app, @StatisticaltestDropDownValueChanged, true);
            app.StatisticaltestDropDown.Value = 'two sample';
            app.indicatormatrixGButton = uibutton(app.LeftPanel, 'push');
            app.indicatormatrixGButton.ButtonPushedFcn = createCallbackFcn(app, @indicatormatrixGButtonPushed, true);
            app.indicatormatrixGButton.Text = 'indicator matrix G';
            app.PermutationEditFieldLabel = uilabel(app.LeftPanel);
            app.PermutationEditFieldLabel.Text = 'Permutation';
            app.PermutationEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PermutationEditField.ValueChangedFcn = createCallbackFcn(app, @PermutationEditFieldValueChanged, true);
            app.PermutationEditField.Value = 50;
            app.PthrtailapproxEditFieldLabel = uilabel(app.LeftPanel);
            app.PthrtailapproxEditFieldLabel.Text = 'Pthr: tail approx';
            app.PthrtailapproxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PthrtailapproxEditField.ValueChangedFcn = createCallbackFcn(app, @PthrtailapproxEditFieldValueChanged, true);
            app.PthrtailapproxEditField.Value = 0.1;
            app.PthresholdEditFieldLabel = uilabel(app.LeftPanel);
            app.PthresholdEditFieldLabel.Text = 'P threshold';
            app.PthresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @PthresholdEditFieldValueChanged, true);
            app.PthresholdEditField.Value = 0.05;
            app.FDRCheckBox = uicheckbox(app.LeftPanel);
            app.FDRCheckBox.ValueChangedFcn = createCallbackFcn(app, @FDRCheckBoxValueChanged, true);
            app.FDRCheckBox.Text = 'FDR';
            app.EigenmodesButton = uibutton(app.LeftPanel, 'push');
            app.EigenmodesButton.ButtonPushedFcn = createCallbackFcn(app, @EigenmodesButtonPushed, true);
            app.EigenmodesButton.Text = 'Eigenmodes';
            app.NumberofmodesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofmodesEditFieldLabel.Text = 'Number of modes';
            app.NumberofmodesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofmodesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofmodesEditFieldValueChanged, true);
            app.NumberofmodesEditField.Value = 150;
            app.HemisphereDropDownLabel = uilabel(app.LeftPanel);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.HemisphereDropDown = uidropdown(app.LeftPanel);
            app.HemisphereDropDown.Items = {'left', 'right'};
            app.HemisphereDropDown.ValueChangedFcn = createCallbackFcn(app, @HemisphereDropDownValueChanged, true);
            app.HemisphereDropDown.Value = 'left';
            app.MostinfluentialmodesEditFieldLabel = uilabel(app.LeftPanel);
            app.MostinfluentialmodesEditFieldLabel.Text = 'Most influential modes';
            app.MostinfluentialmodesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MostinfluentialmodesEditField.ValueChangedFcn = createCallbackFcn(app, @MostinfluentialmodesEditFieldValueChanged, true);
            app.MostinfluentialmodesEditField.Value = 6;
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 't-map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'thresholded t-map')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, '\beta spectrum')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'significant patterns')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.mostinfluentialmodesLabel = uilabel(app.RightPanel);
            app.mostinfluentialmodesLabel.Text = 'most influential modes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end